classdef Apka2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Segmentator4000v100UIFigure  matlab.ui.Figure
        RcznasegmentacjaButton       matlab.ui.control.Button
        UsuobrazButton               matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        Segmentator4000Label         matlab.ui.control.Label
        WygadkrawdzieButton          matlab.ui.control.Button
        StatusGotowyLabel            matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        DisplaystyleButtonGroup      matlab.ui.container.ButtonGroup
        OriginalButton               matlab.ui.control.RadioButton
        BinaryButton                 matlab.ui.control.RadioButton
        PoprawsegmentacjButton       matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        RekonstrukcjaButton          matlab.ui.control.Button
        NastpnyButton                matlab.ui.control.Button
        PoprzedniButton              matlab.ui.control.Button
        WybierzfolderButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        indexOfImage; % Description
        reconstr;
        picLen; % Description
        wiersze; % Description
        kolumny; % Description
        obrazy; % Description
        isPath = 0; % Description
        tabPola;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: WybierzfolderButton
        function WybierzfolderButtonPushed(app, event)
            folder_path = uigetdir;
            if folder_path
                app.isPath = 1;
                file_list = dir(fullfile(folder_path, '*.dcm'));
                num_files = length(file_list);
                app.picLen = num_files;
                
                app.reconstr = zeros(256,256, num_files);
                checker = 0;
                counting = 0;
                srodek = round(num_files/2);
                app.tabPola = zeros(1,num_files);

                for i = srodek:num_files
                    OBRAZ = dicomread(fullfile(folder_path, file_list(i).name));

                    counting = counting + 1;
                
                    I = double(OBRAZ);
                    app.obrazy(:,:,i) = I;
                    
                    app.StatusGotowyLabel.Text = "Status: Przetwarzanie obrazów " + counting + " z " + num_files+"...";
                    
                    if i == srodek
                        figure(1); imshow(I,[]); title('Wybierz strukturę oraz punkt odniesienia');
                        %[xo,yo] = ginput(1);
                        xo = round(100);
                        yo = round(100);
                        
                        hold on;
                        p = plot(xo,yo,'.');
                        set(p,'MarkerSize',15);
                        set(p,'Color','g');
                        
                        %[xp,yp] = ginput(1);
                        xp = round(2);
                        yp = round(2);
                        
                        hold on;
                        p = plot(xp,yp,'.');
                        set(p,'MarkerSize',15);
                        set(p,'Color','r');
                        pause(1);
                        close(figure(1));
                    else
                        if checker == 1
                            xo = round(centroids(1));
                            yo = round(centroids(2));
                            if i == srodek+1
                                xoB = xo;
                                yoB = yo;
                            end
                        end
                        
                        %hold on;
                        %p = plot(xp,yp,'.');
                        %set(p,'MarkerSize',15);
                        %set(p,'Color','r');
                        pause(1);
                    end
                
                    data = reshape(I,[],1);
                
                    klasa_obszaru = 1;
                    klasa_pozaobszarem = 1;
                    ilosc_klas = 3; %zwiększone z 2
                    while (klasa_obszaru == klasa_pozaobszarem)
                        
                        [center,U,obj_fcn] = fcm(data,ilosc_klas,[NaN NaN NaN 0]);
                        [~, nrklasy] = max(U',[],2);
                        I_klasy = zeros(size(I));
                        I_nr_klas = zeros(size(I));
                        maxnrklasy = max(nrklasy);
                        
                        for j = 1:maxnrklasy
                            idx = find(nrklasy == j);
                            I_klasy(idx) = (ones(length(idx),1)*center(j));
                            I_nr_klas(idx) = j;
                        end
                        
                        klasa_obszaru = I_nr_klas(yo,xo);
                        klasa_pozaobszarem = I_nr_klas(yp,xp);
                        ilosc_klas = ilosc_klas+1;
                        
                        if ilosc_klas > 5
                            break;
                        end
                    end
                
                    idx = find(I_nr_klas == klasa_obszaru);
                    macierz = zeros(size(I));
                    macierz(idx) = 1;
                
                    xp1 = xo;
                    yp1 = yo;
                
                    xp2 = xp1;
                    yp2 = yp1;
                
                    wiersz = [xp1 xp2];
                    kolumna = [yp1 yp2];
                
                    czesc_klasy = bwselect(macierz,wiersz,kolumna,4);
                    Bin = czesc_klasy;
    
                    app.wiersze(i) = wiersz(1);
                    app.kolumny(i) = kolumna(1);

                    Obrazek = regionprops(Bin, 'all');
                    area = Obrazek.Area;
                    app.tabPola(1,i) = area;
                    app.Label_2.Text = "Pole: " + app.tabPola(1,i);
                    centroids = cat(1, Obrazek.Centroid);
                    app.reconstr(:,:,i) = Bin;
                    checker = 1;
                    if i == srodek
                        previousAreaB = area;
                    elseif area > 1.3*previousArea
                        elementStr = [1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1];
                        Bin = app.reconstr(:,:,i);
                        
                        wiersz = app.wiersze(i);
                        kolumna = app.kolumny(i);
                        
                        Bin = imerode(Bin, elementStr);
                        Bin = bwselect(Bin, [wiersz, wiersz], [kolumna, kolumna], 4);
                        Bin = imdilate(Bin, elementStr);
                        app.reconstr(:,:,i) = Bin;
                    end
                    
                    app.indexOfImage = i;
                    app.Label.Text = app.indexOfImage + " z " + app.picLen;
                    if app.DisplaystyleButtonGroup.SelectedObject.Text == "Binary"
                        app.Image.ImageSource = repmat(app.reconstr(:,:,app.indexOfImage),1,1,3);
                    elseif app.DisplaystyleButtonGroup.SelectedObject.Text == "Original"
                        notScaled = app.obrazy(:,:,app.indexOfImage);
                        minVal = min(notScaled(:));
                        maxVal = max(notScaled(:));
                        scaled = (notScaled-minVal)/(maxVal-minVal);
                        edges = edge(app.reconstr(:,:,app.indexOfImage));
                        scaled = repmat(scaled,1,1,3);
                        scaled(edges) = 255;
                        app.Image.ImageSource = scaled;
                    end
                    previousArea = area;
                end
                for i = srodek-1:-1:1
                    counting = counting + 1;
                    OBRAZ = dicomread(fullfile(folder_path, file_list(i).name));
                    I = double(OBRAZ);
                    app.obrazy(:,:,i) = I;
                    
                    app.StatusGotowyLabel.Text = "Status: Przetwarzanie obrazów " + counting + " z " + num_files+"...";
                    
                    if i == srodek-1
                        figure(1); imshow(I,[]); title('Wybierz strukturę oraz punkt odniesienia');
                        %[xo,yo] = ginput(1);
                        xo = xoB;
                        yo = yoB;
                        
                        hold on;
                        p = plot(xo,yo,'.');
                        set(p,'MarkerSize',15);
                        set(p,'Color','g');
                        
                        %[xp,yp] = ginput(1);
                        xp = round(2);
                        yp = round(2);
                        
                        hold on;
                        p = plot(xp,yp,'.');
                        set(p,'MarkerSize',15);
                        set(p,'Color','r');
                        pause(1);
                        close(figure(1));
                    else
                        if checker == 1
                            xo = round(centroids(1));
                            yo = round(centroids(2));
                        end
                        
                        %hold on;
                        %p = plot(xp,yp,'.');
                        %set(p,'MarkerSize',15);
                        %set(p,'Color','r');
                        pause(1);
                    end
                
                    data = reshape(I,[],1);
                
                    klasa_obszaru = 1;
                    klasa_pozaobszarem = 1;
                    ilosc_klas = 3; %zwiększone z 2
                    while (klasa_obszaru == klasa_pozaobszarem)
                        
                        [center,U,obj_fcn] = fcm(data,ilosc_klas,[NaN NaN NaN 0]);
                        [~, nrklasy] = max(U',[],2);
                        I_klasy = zeros(size(I));
                        I_nr_klas = zeros(size(I));
                        maxnrklasy = max(nrklasy);
                        
                        for j = 1:maxnrklasy
                            idx = find(nrklasy == j);
                            I_klasy(idx) = (ones(length(idx),1)*center(j));
                            I_nr_klas(idx) = j;
                        end
                        
                        klasa_obszaru = I_nr_klas(yo,xo);
                        klasa_pozaobszarem = I_nr_klas(yp,xp);
                        ilosc_klas = ilosc_klas+1;
                        
                        if ilosc_klas > 5
                            break;
                        end
                    end
                
                    idx = find(I_nr_klas == klasa_obszaru);
                    macierz = zeros(size(I));
                    macierz(idx) = 1;
                
                    xp1 = xo;
                    yp1 = yo;
                
                    xp2 = xp1;
                    yp2 = yp1;
                
                    wiersz = [xp1 xp2];
                    kolumna = [yp1 yp2];
                
                    czesc_klasy = bwselect(macierz,wiersz,kolumna,4);
                    Bin = czesc_klasy;
    
                    app.wiersze(i) = wiersz(1);
                    app.kolumny(i) = kolumna(1);

                    Obrazek = regionprops(Bin, 'all');
                    centroids = cat(1, Obrazek.Centroid);
                    area = Obrazek.Area;
                    app.tabPola(1,i) = area;
                    app.Label_2.Text = "Pole: " + app.tabPola(1,i);
                    app.reconstr(:,:,i) = Bin;
                    checker = 1;
                    if i == srodek-1
                        previousArea = previousAreaB;
                    end
                    if area > 1.3*previousArea
                        elementStr = [1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1];
                        Bin = app.reconstr(:,:,i);
                        
                        wiersz = app.wiersze(i);
                        kolumna = app.kolumny(i);
                        
                        Bin = imerode(Bin, elementStr);
                        Bin = bwselect(Bin, [wiersz, wiersz], [kolumna, kolumna], 4);
                        Bin = imdilate(Bin, elementStr);
                        app.reconstr(:,:,i) = Bin;
                    end
                    
                    app.indexOfImage = i;
                    app.Label.Text = app.indexOfImage + " z " + app.picLen;
                    if app.DisplaystyleButtonGroup.SelectedObject.Text == "Binary"
                        app.Image.ImageSource = repmat(app.reconstr(:,:,app.indexOfImage),1,1,3);
                    elseif app.DisplaystyleButtonGroup.SelectedObject.Text == "Original"
                        notScaled = app.obrazy(:,:,app.indexOfImage);
                        minVal = min(notScaled(:));
                        maxVal = max(notScaled(:));
                        scaled = (notScaled-minVal)/(maxVal-minVal);
                        edges = edge(app.reconstr(:,:,app.indexOfImage));
                        scaled = repmat(scaled,1,1,3);
                        scaled(edges) = 255;
                        app.Image.ImageSource = scaled;
                    end
                    previousArea = area;
                end
                
                app.StatusGotowyLabel.Text = "Status: Gotowy";
            end
        end

        % Button pushed function: RekonstrukcjaButton
        function RekonstrukcjaButtonPushed(app, event)
            promptMessage = 'Czy chcesz zastosować interpolacje podczas rekonstrukcji?';
            button = questdlg(promptMessage, 'Wybór użytkownika', 'TAK', 'NIE', 'NIE');
            if strcmp(button, 'TAK')
                [X,Y,Z] = ndgrid(1:size(app.reconstr,1),1:size(app.reconstr,2),linspace(1,size(app.reconstr,3),100));
                nowamacierz = interp3(app.reconstr,X,Y,Z);
                volshow(nowamacierz)
            else
                volshow(app.reconstr)
            end
        end

        % Button pushed function: PoprzedniButton
        function PoprzedniButtonPushed(app, event)
            app.indexOfImage = app.indexOfImage - 1;
            if app.indexOfImage < 1
                app.indexOfImage = app.picLen;
            end
            app.Label_2.Text = "Pole: " + app.tabPola(1,app.indexOfImage);
            app.Label.Text = app.indexOfImage + " z " + app.picLen;
            if app.DisplaystyleButtonGroup.SelectedObject.Text == "Binary"
                    app.Image.ImageSource = repmat(app.reconstr(:,:,app.indexOfImage),1,1,3);
            elseif app.DisplaystyleButtonGroup.SelectedObject.Text == "Original"
                    notScaled = app.obrazy(:,:,app.indexOfImage);
                    minVal = min(notScaled(:));
                    maxVal = max(notScaled(:));
                    scaled = (notScaled-minVal)/(maxVal-minVal);
                    edges = edge(app.reconstr(:,:,app.indexOfImage));
                    scaled = repmat(scaled,1,1,3);
                    scaled(edges) = 255;
                    app.Image.ImageSource = scaled;
            end
        end

        % Button pushed function: NastpnyButton
        function NastpnyButtonPushed(app, event)
            app.indexOfImage = app.indexOfImage + 1;
            if app.indexOfImage > app.picLen
                app.indexOfImage = 1;
            end
            app.Label_2.Text = "Pole: " + app.tabPola(1,app.indexOfImage);
            app.Label.Text = app.indexOfImage + " z " + app.picLen;
            if app.DisplaystyleButtonGroup.SelectedObject.Text == "Binary"
                    app.Image.ImageSource = repmat(app.reconstr(:,:,app.indexOfImage),1,1,3);
            elseif app.DisplaystyleButtonGroup.SelectedObject.Text == "Original"
                    notScaled = app.obrazy(:,:,app.indexOfImage);
                    minVal = min(notScaled(:));
                    maxVal = max(notScaled(:));
                    scaled = (notScaled-minVal)/(maxVal-minVal);
                    edges = edge(app.reconstr(:,:,app.indexOfImage));
                    scaled = repmat(scaled,1,1,3);
                    scaled(edges) = 255;
                    app.Image.ImageSource = scaled;
            end
        end

        % Button pushed function: PoprawsegmentacjButton
        function PoprawsegmentacjButtonPushed(app, event)
                    promptMessage = 'Czy na pewno chcesz spróbować porawić segmentację tego obrazu?';
                    button = questdlg(promptMessage, 'Wybór użytkownika', 'TAK', 'NIE', 'NIE');
        
                    if strcmp(button, 'TAK')
                      elementStr = [1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1;1 1 1 1 1 1 1];
                        Bin = app.reconstr(:,:,app.indexOfImage);
                        
                        wiersz = app.wiersze(app.indexOfImage);
                        kolumna = app.kolumny(app.indexOfImage);
                        
                        Bin = imerode(Bin, elementStr);
                        %figure(1); subplot(1,3,1); imshow(Bin); title('Kolanko1');
                        Bin = bwselect(Bin, [wiersz, wiersz], [kolumna, kolumna], 4);
                        %figure(1); subplot(1,3,2); imshow(Bin); title('Kolanko2');
                        %hold on;
                        %plot(wiersz,kolumna,'r*');
                        Bin = imdilate(Bin, elementStr);
                        %figure(1); subplot(1,3,3); imshow(Bin); title('Kolanko3');
                        app.reconstr(:,:,app.indexOfImage) = Bin;
                        app.Label.Text = app.indexOfImage + " z " + app.picLen;
                        if app.DisplaystyleButtonGroup.SelectedObject.Text == "Binary"
                            app.Image.ImageSource = repmat(app.reconstr(:,:,app.indexOfImage),1,1,3);
                        elseif app.DisplaystyleButtonGroup.SelectedObject.Text == "Original"
                            notScaled = app.obrazy(:,:,app.indexOfImage);
                            minVal = min(notScaled(:));
                            maxVal = max(notScaled(:));
                            scaled = (notScaled-minVal)/(maxVal-minVal);
                            edges = edge(app.reconstr(:,:,app.indexOfImage));
                            scaled = repmat(scaled,1,1,3);
                            scaled(edges) = 255;
                            app.Image.ImageSource = scaled;
                        end
                    end
        end

        % Selection changed function: DisplaystyleButtonGroup
        function DisplaystyleButtonGroupSelectionChanged(app, event)
            if app.isPath == 1
                if app.DisplaystyleButtonGroup.SelectedObject.Text == "Binary"
                        app.Image.ImageSource = repmat(app.reconstr(:,:,app.indexOfImage),1,1,3);
                elseif app.DisplaystyleButtonGroup.SelectedObject.Text == "Original"
                    notScaled = app.obrazy(:,:,app.indexOfImage);
                    minVal = min(notScaled(:));
                    maxVal = max(notScaled(:));
                    scaled = (notScaled-minVal)/(maxVal-minVal);
                    edges = edge(app.reconstr(:,:,app.indexOfImage));
                    scaled = repmat(scaled,1,1,3);
                    scaled(edges) = 255;
                    app.Image.ImageSource = scaled;
                end
            end
        end

        % Button pushed function: WygadkrawdzieButton
        function WygadkrawdzieButtonPushed(app, event)
            promptMessage = 'Przed wygładzeniem krawędzi upewnij się, że nie trzeba poprawić żadnego obrazu. Czy chcesz kontynuować?';
            button = questdlg(promptMessage, 'Wybór użytkownika', 'TAK', 'NIE', 'NIE');

            if strcmp(button, 'TAK')
                for i = 1:app.picLen
                    app.reconstr(:,:,i) = imdilate(app.reconstr(:,:,i), [1 1 1; 1 1 1; 1 1 1]);
                    app.StatusGotowyLabel.Text = "Status: Przetwarzanie obrazów " + i + " z " + app.picLen+"...";
                end
                if app.DisplaystyleButtonGroup.SelectedObject.Text == "Binary"
                        app.Image.ImageSource = repmat(app.reconstr(:,:,app.indexOfImage),1,1,3);
                elseif app.DisplaystyleButtonGroup.SelectedObject.Text == "Original"
                    notScaled = app.obrazy(:,:,app.indexOfImage);
                    minVal = min(notScaled(:));
                    maxVal = max(notScaled(:));
                    scaled = (notScaled-minVal)/(maxVal-minVal);
                    edges = edge(app.reconstr(:,:,app.indexOfImage));
                    scaled = repmat(scaled,1,1,3);
                    scaled(edges) = 255;
                    app.Image.ImageSource = scaled;
                end
                app.StatusGotowyLabel.Text = "Status: Gotowy ";
            end
        end

        % Button pushed function: UsuobrazButton
        function UsuobrazButtonPushed(app, event)
            promptMessage = 'Czy na pewno chcesz usunąć ten obraz?';
            button = questdlg(promptMessage, 'Wybór użytkownika', 'TAK', 'NIE', 'NIE');
            tempTab = zeros(256,256,app.picLen - 1);
            tempTabReconstr = zeros(256,256,app.picLen - 1);
            tempTabPola = zeros(1,app.picLen - 1); 
            if strcmp(button, 'TAK')
                for i = 1:app.picLen
                    if i < app.indexOfImage
                        tempTab(:,:,i) = app.obrazy(:,:,i);
                        tempTabReconstr(:,:,i) = app.reconstr(:,:,i);
                        tempTabPola(1,i) = app.tabPola(1,i);
                    elseif i > app.indexOfImage
                        tempTab(:,:,i-1) = app.obrazy(:,:,i);
                        tempTabReconstr(:,:,i-1) = app.reconstr(:,:,i);
                        tempTabPola(1,i-1) = app.tabPola(1,i);
                    end
                end
                app.obrazy = tempTab;
                app.reconstr = tempTabReconstr;
                app.tabPola = tempTabPola;
                app.picLen = app.picLen - 1;
                PoprzedniButtonPushed(app,event);
            end
        end

        % Button pushed function: RcznasegmentacjaButton
        function RcznasegmentacjaButtonPushed(app, event)
            I = app.obrazy(:,:,app.indexOfImage);
            figure(1); imshow(I,[]); title('Wybierz strukturę oraz punkt odniesienia');
            [xo,yo] = ginput(1);
                        
            hold on;
            p = plot(xo,yo,'.');
            set(p,'MarkerSize',15);
            set(p,'Color','g');
                        
            xp = round(2);
            yp = round(2);
                        
            hold on;
            p = plot(xp,yp,'.');
            set(p,'MarkerSize',15);
            set(p,'Color','r');
            pause(1);
            close(figure(1));
            data = reshape(I,[],1);
                
                    klasa_obszaru = 1;
                    klasa_pozaobszarem = 1;
                    ilosc_klas = 3; %zwiększone z 2
                    while (klasa_obszaru == klasa_pozaobszarem)
                        
                        [center,U,obj_fcn] = fcm(data,ilosc_klas,[NaN NaN NaN 0]);
                        [~, nrklasy] = max(U',[],2);
                        I_klasy = zeros(size(I));
                        I_nr_klas = zeros(size(I));
                        maxnrklasy = max(nrklasy);
                        
                        for j = 1:maxnrklasy
                            idx = find(nrklasy == j);
                            I_klasy(idx) = (ones(length(idx),1)*center(j));
                            I_nr_klas(idx) = j;
                        end
                        
                        klasa_obszaru = I_nr_klas(yo,xo);
                        klasa_pozaobszarem = I_nr_klas(yp,xp);
                        ilosc_klas = ilosc_klas+1;
                        
                        if ilosc_klas > 5
                            break;
                        end
                    end
                
                    idx = find(I_nr_klas == klasa_obszaru);
                    macierz = zeros(size(I));
                    macierz(idx) = 1;
                
                    xp1 = xo;
                    yp1 = yo;
                
                    xp2 = xp1;
                    yp2 = yp1;
                
                    wiersz = [xp1 xp2];
                    kolumna = [yp1 yp2];
                
                    czesc_klasy = bwselect(macierz,wiersz,kolumna,4);
                    Bin = czesc_klasy;
    
                    app.wiersze(app.indexOfImage) = wiersz(1);
                    app.kolumny(app.indexOfImage) = kolumna(1);

                    Obrazek = regionprops(Bin, 'all');
                    area = Obrazek.Area;
                    app.tabPola(1,app.indexOfImage) = area;
                    app.Label_2.Text = "Pole: " + app.tabPola(1,app.indexOfImage);
                    centroids = cat(1, Obrazek.Centroid);
                    app.reconstr(:,:,app.indexOfImage) = Bin;
                    NastpnyButtonPushed(app,event);
                    PoprzedniButtonPushed(app,event);
                  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Segmentator4000v100UIFigure = uifigure('Visible', 'off');
            app.Segmentator4000v100UIFigure.Name = 'Segmentator4000 v.1.0.0';
            app.Segmentator4000v100UIFigure.Icon = fullfile(pathToMLAPP, 'kolano.png');
            app.WybierzfolderButton = uibutton(app.Segmentator4000v100UIFigure, 'push');
            app.WybierzfolderButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzfolderButtonPushed, true);
            app.WybierzfolderButton.Text = 'Wybierz folder';
            app.PoprzedniButton = uibutton(app.Segmentator4000v100UIFigure, 'push');
            app.PoprzedniButton.ButtonPushedFcn = createCallbackFcn(app, @PoprzedniButtonPushed, true);
            app.PoprzedniButton.Text = 'Poprzedni';
            app.NastpnyButton = uibutton(app.Segmentator4000v100UIFigure, 'push');
            app.NastpnyButton.ButtonPushedFcn = createCallbackFcn(app, @NastpnyButtonPushed, true);
            app.NastpnyButton.Text = 'Następny';
            app.RekonstrukcjaButton = uibutton(app.Segmentator4000v100UIFigure, 'push');
            app.RekonstrukcjaButton.ButtonPushedFcn = createCallbackFcn(app, @RekonstrukcjaButtonPushed, true);
            app.RekonstrukcjaButton.Text = 'Rekonstrukcja';
            app.Image = uiimage(app.Segmentator4000v100UIFigure);
            app.PoprawsegmentacjButton = uibutton(app.Segmentator4000v100UIFigure, 'push');
            app.PoprawsegmentacjButton.ButtonPushedFcn = createCallbackFcn(app, @PoprawsegmentacjButtonPushed, true);
            app.PoprawsegmentacjButton.Text = 'Popraw segmentację';
            app.DisplaystyleButtonGroup = uibuttongroup(app.Segmentator4000v100UIFigure);
            app.DisplaystyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplaystyleButtonGroupSelectionChanged, true);
            app.DisplaystyleButtonGroup.Title = 'Display style';
            app.BinaryButton = uiradiobutton(app.DisplaystyleButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.BinaryButton.Value = true;
            app.OriginalButton = uiradiobutton(app.DisplaystyleButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.Label = uilabel(app.Segmentator4000v100UIFigure);
            app.Label.Text = '';
            app.StatusGotowyLabel = uilabel(app.Segmentator4000v100UIFigure);
            app.StatusGotowyLabel.Text = 'Status: Gotowy';
            app.WygadkrawdzieButton = uibutton(app.Segmentator4000v100UIFigure, 'push');
            app.WygadkrawdzieButton.ButtonPushedFcn = createCallbackFcn(app, @WygadkrawdzieButtonPushed, true);
            app.WygadkrawdzieButton.Text = 'Wygładź krawędzie';
            app.Segmentator4000Label = uilabel(app.Segmentator4000v100UIFigure);
            app.Segmentator4000Label.Text = 'Segmentator 4000';
            app.Label_2 = uilabel(app.Segmentator4000v100UIFigure);
            app.Label_2.Text = '';
            app.UsuobrazButton = uibutton(app.Segmentator4000v100UIFigure, 'push');
            app.UsuobrazButton.ButtonPushedFcn = createCallbackFcn(app, @UsuobrazButtonPushed, true);
            app.UsuobrazButton.Text = 'Usuń obraz';
            app.RcznasegmentacjaButton = uibutton(app.Segmentator4000v100UIFigure, 'push');
            app.RcznasegmentacjaButton.ButtonPushedFcn = createCallbackFcn(app, @RcznasegmentacjaButtonPushed, true);
            app.RcznasegmentacjaButton.Text = 'Ręczna segmentacja';
            % Show the figure after all components are created
            app.Segmentator4000v100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Apka2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Segmentator4000v100UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Segmentator4000v100UIFigure)
        end
    end
end